classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ZresetujsymulacjButton        matlab.ui.control.Button
        ZatrzymajsymulacjButton       matlab.ui.control.Button
        UstawieniasymulacjiLabel      matlab.ui.control.Label
        GstoczstkiEditField           matlab.ui.control.NumericEditField
        GstoczstkiEditFieldLabel      matlab.ui.control.Label
        Gstoczstkikgm3EditField       matlab.ui.control.NumericEditField
        Gstoczstkikgm3EditFieldLabel  matlab.ui.control.Label
        PromiecztkimEditField         matlab.ui.control.NumericEditField
        PromiecztkimEditFieldLabel    matlab.ui.control.Label
        PrdkopowietrzamsEditField     matlab.ui.control.NumericEditField
        PrdkopowietrzamsLabel         matlab.ui.control.Label
        Gstopowietrzakgm3EditField    matlab.ui.control.NumericEditField
        Gstopowietrzakgm3Label        matlab.ui.control.Label
        PresetDropDown                matlab.ui.control.DropDown
        PozycjapocztkowaobiektunaosiXcmSlider  matlab.ui.control.Slider
        PozycjapocztkowaobiektunaosiXcmSliderLabel  matlab.ui.control.Label
        PresetDropDownLabel           matlab.ui.control.Label
        AmplitudaEditField            matlab.ui.control.NumericEditField
        Amplitudakgm3Label            matlab.ui.control.Label
        CzstotliwoHzEditField         matlab.ui.control.NumericEditField
        CzstotliwoHzEditFieldLabel    matlab.ui.control.Label
        PoziomszczegowocisymulacjiDropDown  matlab.ui.control.DropDown
        PoziomszczegowocisymulacjiDropDownLabel  matlab.ui.control.Label
        DefinicjastaychLabel          matlab.ui.control.Label
        UruchomsymulacjButton         matlab.ui.control.Button
        UIAxes2_3                     matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    Property % Description
    isRunning
    isReset
    frequency
    wavelength
    amplitude
    rho_air
    c_air
    mu_air
    radius
    rho_particle
    mass
    K1
    K2
    t
    dt
    state
    timer
    params
    posLine
    velLine
    simulationDetailLevel = 1
    end
    
    methods (Access = private)

        % Run simulation


        function setDefaultParameters(app)
            app.frequency = 40000;
            app.amplitude = 400;
            app.rho_air = 1.225;
            app.c_air = 343;
            app.mu_air = 1.82e-5;
            app.radius = 0.01;
            app.rho_particle = 0.01;
            app.recalculateDependentParameters();
        end

        function updateParametersBasedOnPreset(app, selectedPreset)
            switch selectedPreset
                case 'Styrofoam'
                    app.rho_particle = 0.01;
                    app.radius = 0.01;
                case 'Wood'
                    app.rho_particle = 700;
                    app.radius = 1.5e-4;
                case 'Glass'
                    app.rho_particle = 2500;
                    app.radius = 1.2e-4;
                case 'Steel'
                    app.rho_particle = 7800;
                    app.radius = 0.8e-4;
            end
            app.recalculateDependentParameters();
        end

function recalculateDependentParameters(app)
    app.wavelength = 343 / app.frequency;
    app.mass = (4/3) * pi * app.radius^3 * app.rho_particle;
    app.K1 = (1 / (4 * pi * app.c_air^2 * app.rho_air)) * (1 - (app.rho_air / app.rho_particle));
    app.K2 = (1 / (8 * pi * app.c_air^2 * app.rho_air)) * ((app.rho_particle - app.rho_air) / (2 * app.rho_particle + app.rho_air));
end


        function updateUIFields(app)
            app.Gstoczstkikgm3EditField.Value = app.rho_particle;
            app.PromiecztkimEditField.Value = app.radius;
            app.CzstotliwoHzEditField.Value = app.frequency;
            app.AmplitudaEditField.Value = app.amplitude;
            app.Gstopowietrzakgm3EditField.Value = app.rho_air;
            app.PrdkopowietrzamsEditField.Value = app.c_air;
        end

        
        function updateSimulation(app)
            app.state = ball_motion(0, app.state, app.params);
            app.t = app.t + app.dt;
            z = app.state(1);
            v = app.state(2);
            addpoints(app.posLine, app.t, z);
            addpoints(app.velLine, app.t, v);
            drawnow;
        end
        
        function setSimulationDetailLevel(app, level)
            switch level
                case 'Podstawowy'
                    app.simulationDetailLevel = 1;
                case 'Średni'
                    app.simulationDetailLevel = 2;
                case 'Zaawansowany'
                    app.simulationDetailLevel = 3;
            end
        end


    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

    app.setDefaultParameters();
    app.updateUIFields();

        end

        % Button pushed function: UruchomsymulacjButton
        function ButtonPushed(app, event)
        % Update parameters based on user input
        app.frequency = app.CzstotliwoHzEditField.Value;
        app.amplitude = app.AmplitudaEditField.Value;
        app.rho_air = app.Gstopowietrzakgm3EditField.Value;
        app.c_air = app.PrdkopowietrzamsEditField.Value;
        app.radius = app.PromiecztkimEditField.Value;
        app.rho_particle = app.Gstoczstkikgm3EditField.Value;

        % Recalculate dependent parameters
        app.recalculateDependentParameters();

        % Initialize parameters
        app.params = struct(...
            'frequency', app.frequency, ...
            'wavelength', app.wavelength, ...
            'amplitude', app.amplitude, ...
            'rho_air', app.rho_air, ...
            'c_air', app.c_air, ...
            'mu_air', app.mu_air, ...
            'radius', app.radius, ...
            'rho_particle', app.rho_particle, ...
            'mass', app.mass, ...
            'K1', app.K1, ...
            'K2', app.K2, ...
            'transducer_distance', 100 * app.wavelength, ...
            'q_particle', 1e-9, ...
            'epsilon_0', 8.854e-12, ...
            'A_vdw', 1e-20, ...
            'T_gradient', 1e2, ...
            'alpha_thermal', 1e-3, ...
            'diameter', 2 * app.radius ...
        );

        % Initial conditions
        z0 = 50.5 * app.params.wavelength;
        v0 = 0; % m/s
        app.state = [z0; v0];

        % Initialize time
        app.t = 0;
        app.dt = 0.001; % Time step

        % Initialize plot data
        times = [];
        positions = [];
        velocities = [];

        % Set control variables
        app.isRunning = true;
        app.isReset = false;

        % Ensure simulationDetailLevel is numeric
        detailLevel = app.simulationDetailLevel;

        % Select simulation detail level
        switch detailLevel
            case 1
                % Basic level simulation
                while app.isRunning
                    app.state = ball_motion(0, app.state, app.params);
                    app.t = app.t + app.dt;
                    z = app.state(1);
                    v = app.state(2);
                    times(end+1) = app.t; %#ok<AGROW>
                    positions(end+1) = z; %#ok<AGROW>
                    velocities(end+1) = v; %#ok<AGROW>
                    plot(app.UIAxes2, times, positions);
                    title(app.UIAxes2, 'Position of the particle');
                    xlabel(app.UIAxes2, 'Time (s)');
                    ylabel(app.UIAxes2, 'Position (m)');
                    drawnow;
                    plot(app.UIAxes2_2, times, velocities);
                    title(app.UIAxes2_2, 'Velocity of the particle');
                    xlabel(app.UIAxes2_2, 'Time (s)');
                    ylabel(app.UIAxes2_2, 'Velocity (m/s)');
                    drawnow;
                    pause(app.dt);
                    if app.isReset
                        break;
                    end
                end
            case 2
                % Intermediate level simulation (use ode45)
                tspan = [0 5]; % from 0 to 5 seconds
                [app.t, app.state] = ode45(@(t, state) ball_motion(t, state, app.params), tspan, [z0; v0]);
                positions = app.state(:, 1);
                velocities = app.state(:, 2);
                plot(app.UIAxes2, app.t, positions);
                title(app.UIAxes2, 'Position of the particle');
                xlabel(app.UIAxes2, 'Time (s)');
                ylabel(app.UIAxes2, 'Position (m)');
                plot(app.UIAxes2_2, app.t, velocities);
                title(app.UIAxes2_2, 'Velocity of the particle');
                xlabel(app.UIAxes2_2, 'Time (s)');
                ylabel(app.UIAxes2_2, 'Velocity (m/s)');
            case 3
                % Advanced level simulation (Python code translated to MATLAB)
                z0 = 50.5 * app.params.wavelength;
                v0 = 0; % m/s
                state0 = [z0; v0];
                transducer_distance = 100 * app.params.wavelength;
                t = linspace(0, 15, 1000000);
                dt = t(2) - t(1);

                % Initialize solution
                solution = zeros(length(t), 2);
                solution(1, :) = state0;

                % Run simulation using Runge-Kutta 4
                state = state0;
                for i = 2:length(t)
                    state = runge_kutta_4(state, dt, transducer_distance, app.params);
                    solution(i, :) = state;
                end

                % Extract positions and velocities
                positions = solution(:, 1);
                velocities = solution(:, 2);

                % Plot results
                plot(app.UIAxes2, t, positions);
                title(app.UIAxes2, 'Position of the particle');
                xlabel(app.UIAxes2, 'Time (s)');
                ylabel(app.UIAxes2, 'Position (m)');

                plot(app.UIAxes2_2, t, velocities);
                title(app.UIAxes2_2, 'Velocity of the particle');
                xlabel(app.UIAxes2_2, 'Time (s)');
                ylabel(app.UIAxes2_2, 'Velocity (m/s)');
        end

        function state = runge_kutta_4(state, dt, transducer_distance, params)
            k1 = ball_acceleration(state, transducer_distance, params);
            k2 = ball_acceleration(state + 0.5 * dt * k1, transducer_distance, params);
            k3 = ball_acceleration(state + 0.5 * dt * k2, transducer_distance, params);
            k4 = ball_acceleration(state + dt * k3, transducer_distance, params);
            state = state + (dt / 6.0) * (k1 + 2*k2 + 2*k3 + k4);
        end

        function accel = ball_acceleration(state, transducer_distance, params)
            z = state(1);
            v = state(2);
            k = 2 * pi / params.wavelength;
            amplitude_effective = acoustic_field_amplitude(z, transducer_distance, z, params.radius, params);
            d_gorkov_potential_dz = -(8 / 3) * pi * params.radius^3 * params.K1 * amplitude_effective^2 * k * sin(2 * k * z) * (1 + cos(k * (transducer_distance - 2 * z)));
            acoustic_force_gradient = d_gorkov_potential_dz;
            Re = (params.rho_air * v * params.diameter) / params.mu_air;

            if Re < 1
                drag_force = -6 * pi * params.mu_air * params.radius * v;
            else
                Cd = 24 / Re + 6 / (1 + sqrt(Re)) + 0.4;
                drag_force = -0.5 * Cd * params.rho_air * pi * params.radius^2 * v^2;
            end

            streaming_force = 0.5 * amplitude_effective^2 * params.rho_air * params.c_air * sin(k * z);
            cavitation_damping = -0.1 * v * (amplitude_effective > 1e6);
            electrostatic_f = electrostatic_force(z, params);
            vdw_f = van_der_waals_force(z, params);
            thermophoretic_f = thermophoretic_force(z, params);
            gravity_force = -params.mass * 9.81;
            total_force = acoustic_force_gradient + drag_force + streaming_force + cavitation_damping + electrostatic_f + vdw_f + thermophoretic_f + gravity_force;
            dv_dt = total_force / params.mass;
            accel = [v; dv_dt];
        end

        function amplitude_effective = acoustic_field_amplitude(z, transducer_distance, particle_z, particle_radius, params)
            k = 2 * pi / params.wavelength;
            distance_factor = sin(k * z) * exp(-z / transducer_distance);
            amplitude_effective = params.amplitude * distance_factor;
            distance_to_particle = abs(z - particle_z);
            if distance_to_particle < particle_radius
                amplitude_effective = amplitude_effective * (1 - 0.5 * (particle_radius - distance_to_particle) / particle_radius);
            end
        end

        function f = electrostatic_force(z, params)
            E_field = params.amplitude / (4 * pi * params.epsilon_0 * z^2);
            f = params.q_particle * E_field;
        end

        function f = van_der_waals_force(z, params)
            f = -params.A_vdw / (6 * z^3);
        end

        function f = thermophoretic_force(z, params)
            f = -params.alpha_thermal * params.T_gradient;
        end

        function dstate_dt = ball_motion(~, state, params)
            z = state(1);
            v = state(2);
            k = 2 * pi / params.wavelength;
            distance_factor = sin(k * z) * exp(-z / params.transducer_distance);
            amplitude_effective = params.amplitude * distance_factor;
            d_gorkov_potential_dz = -(8 / 3) * pi * params.radius^3 * params.K1 * amplitude_effective^2 * k * sin(2 * k * z) * (1 + cos(k * (params.transducer_distance - 2 * z)));
            acoustic_force_gradient = d_gorkov_potential_dz;
            drag_force = -6 * pi * params.mu_air * params.radius * v;
            gravity_force = -params.mass * 9.81;
            total_force = acoustic_force_gradient + drag_force + gravity_force;
            dv_dt = total_force / params.mass;
            dz_dt = v;
            dstate_dt = [dz_dt; dv_dt];
        end

        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            selectedPreset = app.PresetDropDown.Value;
            app.setDefaultParameters();
            app.updateParametersBasedOnPreset(selectedPreset);
            app.updateUIFields();
        end

        % Button pushed function: ZatrzymajsymulacjButton
        function ZatrzymajsymulacjButtonPushed(app, event)

            app.isRunning = false;
        end

        % Button pushed function: ZresetujsymulacjButton
        function ZresetujsymulacjButtonPushed(app, event)
            app.isRunning = false;
            app.isReset = true;
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
        end

        % Value changed function: PoziomszczegowocisymulacjiDropDown
        function PoziomszczegowocisymulacjiDropDownValueChanged(app, event)
            value = app.PoziomszczegowocisymulacjiDropDown.Value;
            app.setSimulationDetailLevel(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Położenie')
            xlabel(app.UIAxes2, 'Czas [s]')
            ylabel(app.UIAxes2, 'Położenie [y]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Prędkość')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Siły')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UruchomsymulacjButton = uibutton(app.UIFigure, 'push');
            app.UruchomsymulacjButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UruchomsymulacjButton.Text = 'Uruchom symulację';
            app.DefinicjastaychLabel = uilabel(app.UIFigure);
            app.DefinicjastaychLabel.Text = 'Definicja stałych';
            app.PoziomszczegowocisymulacjiDropDownLabel = uilabel(app.UIFigure);
            app.PoziomszczegowocisymulacjiDropDownLabel.Text = 'Poziom szczegółowości symulacji';
            app.PoziomszczegowocisymulacjiDropDown = uidropdown(app.UIFigure);
            app.PoziomszczegowocisymulacjiDropDown.Items = {'Podstawowy', 'Średni', 'Zaawansowany'};
            app.PoziomszczegowocisymulacjiDropDown.ValueChangedFcn = createCallbackFcn(app, @PoziomszczegowocisymulacjiDropDownValueChanged, true);
            app.PoziomszczegowocisymulacjiDropDown.Value = 'Podstawowy';
            app.CzstotliwoHzEditFieldLabel = uilabel(app.UIFigure);
            app.CzstotliwoHzEditFieldLabel.Text = 'Częstotliwość [Hz]';
            app.CzstotliwoHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.Amplitudakgm3Label = uilabel(app.UIFigure);
            app.Amplitudakgm3Label.Text = 'Amplituda ';
            app.AmplitudaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PresetDropDownLabel = uilabel(app.UIFigure);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PozycjapocztkowaobiektunaosiXcmSliderLabel = uilabel(app.UIFigure);
            app.PozycjapocztkowaobiektunaosiXcmSliderLabel.Text = 'Pozycja początkowa obiektu na osi X [cm]';
            app.PozycjapocztkowaobiektunaosiXcmSlider = uislider(app.UIFigure);
            app.PozycjapocztkowaobiektunaosiXcmSlider.Limits = [-100 100];
            app.PresetDropDown = uidropdown(app.UIFigure);
            app.PresetDropDown.Items = {'Styrofoam', 'Wood', 'Glass', 'Steel'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.PresetDropDown.Value = 'Styrofoam';
            app.Gstopowietrzakgm3Label = uilabel(app.UIFigure);
            app.Gstopowietrzakgm3Label.Text = 'Gęstość powietrza [kg/m^3]';
            app.Gstopowietrzakgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.PrdkopowietrzamsLabel = uilabel(app.UIFigure);
            app.PrdkopowietrzamsLabel.Text = 'Prędkość powietrza [m/s]';
            app.PrdkopowietrzamsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PromiecztkimEditFieldLabel = uilabel(app.UIFigure);
            app.PromiecztkimEditFieldLabel.Text = 'Promień cząśtki [m]';
            app.PromiecztkimEditField = uieditfield(app.UIFigure, 'numeric');
            app.Gstoczstkikgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Gstoczstkikgm3EditFieldLabel.Text = 'Gęstość cząstki [kg / m3]';
            app.Gstoczstkikgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.GstoczstkiEditFieldLabel = uilabel(app.UIFigure);
            app.GstoczstkiEditFieldLabel.Text = 'Gęstość cząstki';
            app.GstoczstkiEditField = uieditfield(app.UIFigure, 'numeric');
            app.UstawieniasymulacjiLabel = uilabel(app.UIFigure);
            app.UstawieniasymulacjiLabel.Text = 'Ustawienia symulacji';
            app.ZatrzymajsymulacjButton = uibutton(app.UIFigure, 'push');
            app.ZatrzymajsymulacjButton.ButtonPushedFcn = createCallbackFcn(app, @ZatrzymajsymulacjButtonPushed, true);
            app.ZatrzymajsymulacjButton.Text = 'Zatrzymaj symulację';
            app.ZresetujsymulacjButton = uibutton(app.UIFigure, 'push');
            app.ZresetujsymulacjButton.ButtonPushedFcn = createCallbackFcn(app, @ZresetujsymulacjButtonPushed, true);
            app.ZresetujsymulacjButton.Text = 'Zresetuj symulację';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end